classdef Meteor_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeteorUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Densitykg3DropDownLabel     matlab.ui.control.Label
        Densitykg3DropDown          matlab.ui.control.DropDown
        AngleofArrivalLabel         matlab.ui.control.Label
        AngleofArrivalSlider        matlab.ui.control.Slider
        RunSimulationButton         matlab.ui.control.Button
        MeteorEntrySimulationLabel  matlab.ui.control.Label
        MeteorMasskgLabel           matlab.ui.control.Label
        MeteorWeightEditField       matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        LayerreachedLabel           matlab.ui.control.Label
        layerLabel                  matlab.ui.control.Label
        heightLabel                 matlab.ui.control.Label
        HeightReachedLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mass = 1; % Mass of meteor from text field
        density = 10; % Density of meteor from text field
        angle = 45; % angle of arrival of meteor
        l = "";
        hb = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MeteorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {424, 424};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            metObj = meteor_class;
            [lay, he] = metObj.simulate_meteor(app.mass, app.density, app.angle);
            app.layerLabel.Text = lay;
            app.heightLabel.Text = strcat(string(he)," m");
        end

        % Callback function
        function MeteorWeightEditFieldSValueChanged(app, event)
            app.mass = 11;
            
        end

        % Value changed function: Densitykg3DropDown
        function Densitykg3DropDownValueChanged(app, event)
            app.density = str2double(app.Densitykg3DropDown.Value);
            
        end

        % Value changed function: AngleofArrivalSlider
        function AngleofArrivalSliderValueChanged(app, event)
            app.angle = app.AngleofArrivalSlider.Value;
            
        end

        % Value changed function: MeteorWeightEditField
        function MeteorWeightEditFieldValueChanged(app, event)
            app.mass = app.MeteorWeightEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeteorUIFigure = uifigure('Visible', 'off');
            app.MeteorUIFigure.Name = 'Meteor Simulation';
            app.MeteorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MeteorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Densitykg3DropDownLabel = uilabel(app.LeftPanel);
            app.Densitykg3DropDownLabel.Text = 'Density (kg^3)';
            app.Densitykg3DropDown = uidropdown(app.LeftPanel);
            app.Densitykg3DropDown.Items = {'1000', '3000', '5000', '7000'};
            app.Densitykg3DropDown.ValueChangedFcn = createCallbackFcn(app, @Densitykg3DropDownValueChanged, true);
            app.Densitykg3DropDown.Value = '3000';
            app.AngleofArrivalLabel = uilabel(app.LeftPanel);
            app.AngleofArrivalLabel.Text = 'Angle of Arrival (°) ';
            app.AngleofArrivalSlider = uislider(app.LeftPanel);
            app.AngleofArrivalSlider.Limits = [0 90];
            app.AngleofArrivalSlider.ValueChangedFcn = createCallbackFcn(app, @AngleofArrivalSliderValueChanged, true);
            app.AngleofArrivalSlider.Value = 45;
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.MeteorEntrySimulationLabel = uilabel(app.LeftPanel);
            app.MeteorEntrySimulationLabel.Text = 'Meteor Entry Simulation';
            app.MeteorMasskgLabel = uilabel(app.LeftPanel);
            app.MeteorMasskgLabel.Text = 'Meteor Mass (kg)';
            app.MeteorWeightEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MeteorWeightEditField.Limits = [0.0001 1000];
            app.MeteorWeightEditField.ValueChangedFcn = createCallbackFcn(app, @MeteorWeightEditFieldValueChanged, true);
            app.MeteorWeightEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.LayerreachedLabel = uilabel(app.RightPanel);
            app.LayerreachedLabel.Text = 'Layer reached: ';
            app.layerLabel = uilabel(app.RightPanel);
            app.layerLabel.Text = '';
            app.heightLabel = uilabel(app.RightPanel);
            app.heightLabel.Text = '';
            app.HeightReachedLabel = uilabel(app.RightPanel);
            app.HeightReachedLabel.Text = 'Height Reached: ';
            % Show the figure after all components are created
            app.MeteorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Meteor_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeteorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeteorUIFigure)
        end
    end
end